classdef ImageFiltering < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        ImageProcessingFilteringLabel  matlab.ui.control.Label
        ApplyFilterButton              matlab.ui.control.Button
        ChooseafilterDropDown          matlab.ui.control.DropDown
        ChooseafilterDropDownLabel     matlab.ui.control.Label
        LoadImageButton                matlab.ui.control.Button
        UIAxes2                        matlab.ui.control.UIAxes
        UIAxes                         matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        Property % Description
        inputImage;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadImageButton
        function LoadImageButtonPushed(app, event)
            %get the name of the image
            %get the path of the image
            [fname, pname] = uigetfile('*.*');

            %read the image
            app.inputImage = imread(fname);

            %display the image
            imshow(app.inputImage, 'Parent', app.UIAxes);
        end

        % Button pushed function: ApplyFilterButton
        function ApplyFilterButtonPushed(app, event)
            userSelection = app.ChooseafilterDropDown.Value;

            if(userSelection == "Low Pass Filter")
                filter1 = fspecial("average");
                outImage1 = imfilter(app.inputImage, filter1);
                imshow(outImage1, 'Parent', app.UIAxes2);

            elseif(userSelection == "Gaussian Filter")
                filter2 = fspecial("gaussian");
                outImage2 = imfilter(app.inputImage, filter2);
                imshow(outImage2, 'Parent', app.UIAxes2);

            elseif(userSelection == "Laplacian Filter")
                filter3 = fspecial("lapacian");
                outImage3 = imfilter(app.inputImage, filter3);
                imshow(outImage3, 'Parent', app.UIAxes2);

            elseif(userSelection == "Sobel Filter")
                filter4 = fspecial("sobel");
                outImage4 = imfilter(app.inputImage, filter4);
                imshow(outImage4, 'Parent', app.UIAxes2);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Input Image')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.ZTick = [];
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Output Image')
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.UIAxes2.ZTick = [];
            app.UIAxes2.SubtitleFontWeight = 'bold';
            app.LoadImageButton = uibutton(app.UIFigure, 'push');
            app.LoadImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImageButtonPushed, true);
            app.LoadImageButton.Text = 'Load Image';
            app.ChooseafilterDropDownLabel = uilabel(app.UIFigure);
            app.ChooseafilterDropDownLabel.Text = 'Choose a filter';
            app.ChooseafilterDropDown = uidropdown(app.UIFigure);
            app.ChooseafilterDropDown.Items = {'Low Pass Filter', 'Gaussian Filter', 'Laplacian Filter', 'Sobel Filter'};
            app.ChooseafilterDropDown.Value = 'Low Pass Filter';
            app.ApplyFilterButton = uibutton(app.UIFigure, 'push');
            app.ApplyFilterButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyFilterButtonPushed, true);
            app.ApplyFilterButton.Text = 'Apply Filter';
            app.ImageProcessingFilteringLabel = uilabel(app.UIFigure);
            app.ImageProcessingFilteringLabel.Text = 'Image Processing Filtering';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ImageFiltering

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end